classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MainPanel                     matlab.ui.container.Panel
        Tabs                          matlab.ui.container.TabGroup
        Data                          matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        NextdataselectionButtonGroup  matlab.ui.container.ButtonGroup
        HealthyButton                 matlab.ui.control.RadioButton
        CerebralPalsyButton           matlab.ui.control.RadioButton
        Graphs                        matlab.ui.container.Tab
        GraphsPanel                   matlab.ui.container.Panel
        inputAxes                     matlab.ui.control.UIAxes
        outputAxes                    matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        PlotoptionsTab                matlab.ui.container.Tab
        SelectdataLabel               matlab.ui.control.Label
        HealthyCheckBox               matlab.ui.control.CheckBox
        CerebralPalsyCheckBox         matlab.ui.control.CheckBox
        HealthyPeaksLabel             matlab.ui.control.Label
        MaximaHealthyCheckBox         matlab.ui.control.CheckBox
        MinimahealthyCheckBox         matlab.ui.control.CheckBox
        PalsyPeaksLabel               matlab.ui.control.Label
        MaximaPalsyCheckBox           matlab.ui.control.CheckBox
        MinimaPalsyCheckBox           matlab.ui.control.CheckBox
        FourieroptionsTab             matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        WindowDropDownLabel           matlab.ui.control.Label
        WindowDropDown                matlab.ui.control.DropDown
        UpdateButton                  matlab.ui.control.Button
        DataoptionsTab                matlab.ui.container.Tab
        SmoothingLabel                matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        WindowSizeSliderLabel         matlab.ui.control.Label
        WindowSizeSlider              matlab.ui.control.Slider
        SaveTab                       matlab.ui.container.Tab
        CommentsTextAreaLabel         matlab.ui.control.Label
        Comments                      matlab.ui.control.TextArea
        SaveasButton                  matlab.ui.control.Button
        openFilePanel                 matlab.ui.container.Panel
        OpenfileButton                matlab.ui.control.Button
        CurrentfileEditFieldLabel     matlab.ui.control.Label
        CurrentfileEditField          matlab.ui.control.EditField
        HelpButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        healthyData         % Contains the data of a healthy child
        cerebralPalsyData   % Contains the data of a child w/ cerebral palsy
        healthyDataSmoothed 
        cerebralPalsyDataSmoothed
        HelpPopup           %Help window
        smoothOn = false;
        %maxima and minima for both data sets
        Palsymaxima
        Healthymaxima
        Palsyminima
        Healthyminima
        %-----------------
        
        y1 % health data after fourier
        y2 % palsy data after fourier

    end
    
    methods (Access = private)
        
        function showInputData(app, healthyData, cerebralPalsyData)
            
            cla(app.inputAxes); %clear graph
            
            if app.HealthyCheckBox.Value == 1 %If healthy is on: draw healthy data
                plot(app.inputAxes, healthyData);
                if app.CerebralPalsyCheckBox.Value == 1 %If other data needs to be plotted, hold on
                    hold(app.inputAxes, 'on');
                end
                legend(app.inputAxes,'Healthy')
            end
            
            if app.CerebralPalsyCheckBox.Value == 1
                plot(app.inputAxes, cerebralPalsyData);
                if app.HealthyCheckBox.Value == 1 %Hold off if hold on was used before
                    %hold(app.inputAxes, 'off');
                end
                legend(app.inputAxes,'CerebralPalsy')
            end
            
            if app.CerebralPalsyCheckBox.Value == 1 && app.HealthyCheckBox.Value == 1 
                legend(app.inputAxes,'Healthy','CerebralPalsy')
            end
            
            %calls calculate method for maxima & minima, calls plot method
            if app.MaximaHealthyCheckBox.Value == 1
                calculateMaxima(app)
                plotHealthycircles(app, app.Healthymaxima, 'r')
            end
            if app.MaximaPalsyCheckBox.Value == 1
                calculateMaxima(app)
                plotPalsycircles(app, app.Palsymaxima, 'r')
            end
            if app.MinimahealthyCheckBox.Value == 1
                calculateMinima(app)
                plotHealthycircles(app, app.Healthyminima, 'g')
            end
            if app.MinimaPalsyCheckBox.Value == 1
                calculateMinima(app)
                plotPalsycircles(app, app.Palsyminima, 'g')
            end

            
        end

    %calculate the maxima for healthy and palsy data 
        function calculateMaxima(app)
            if app.smoothOn == true
                app.Healthymaxima = findpeaks(app.healthyDataSmoothed)
                app.Palsymaxima = findpeaks(app.cerebralPalsyDataSmoothed)
            else 
                app.Healthymaxima = findpeaks(app.healthyData)
                app.Palsymaxima = findpeaks(app.cerebralPalsyData)
            end           
        end
    
    %calculate the minima for  healthy and palsy data
        function calculateMinima(app)
            if app.smoothOn == true
                %healthy minima
                DataInv = 1.01*max(app.healthyDataSmoothed) - app.healthyDataSmoothed;
                [Minima,MinIdx] = findpeaks(DataInv)
                app.Healthyminima = app.healthyDataSmoothed(MinIdx);
                %palsy minima
                DataInv = 1.01*max(app.cerebralPalsyDataSmoothed) - app.cerebralPalsyDataSmoothed;
                [Minima,MinIdx] = findpeaks(DataInv)
                app.Palsyminima = app.cerebralPalsyDataSmoothed(MinIdx);
            else 
                %healthy minima
                DataInv = 1.01*max(app.healthyData) - app.healthyData;
                [Minima,MinIdx] = findpeaks(DataInv)
                app.Healthyminima = app.healthyData(MinIdx);
                %palsy minima
                DataInv = 1.01*max(app.cerebralPalsyData) - app.cerebralPalsyData;
                [Minima,MinIdx] = findpeaks(DataInv)
                app.Palsyminima = app.cerebralPalsyData(MinIdx);
        
            end
           
        end
            
    
        %plot the circles for the minima or maxima peaks on the healthy data
        function plotHealthycircles(app, peaks, color) 
            for i = 1:size(peaks)
                %determine center of circle
                if app.smoothOn == true
                    center=[find(app.healthyDataSmoothed==peaks(i)),peaks(i)]
                else
                    center=[find(app.healthyData==peaks(i)),peaks(i)]
                end
                    
                hold(app.inputAxes, 'on');
                
                th = 0:pi/50:2*pi;
                xunit = 1 * cos(th) + center(1);
                yunit = 1 * sin(th) + center(2);
                plot(app.inputAxes,xunit, yunit, color);      
            end
            legend(app.inputAxes,'Healthy','CerebralPalsy')
        end
    
        %plot the circles for the minima or maxima peaks on the palsy data
        function plotPalsycircles(app, peaks, color) 
            for i = 1:size(peaks)
                if app.smoothOn == true
                    center=[find(app.cerebralPalsyDataSmoothed==peaks(i)),peaks(i)]
                else
                    center=[find(app.cerebralPalsyData==peaks(i)),peaks(i)]
                end
                        
                hold(app.inputAxes, 'on');
                th = 0:pi/50:2*pi;
                xunit = 1 * cos(th) + center(1);
                yunit = 1 * sin(th) + center(2);
                plot(app.inputAxes,xunit, yunit, color);      
            end
            legend(app.inputAxes,'Healthy','CerebralPalsy')
            
        end
    
        %Check if the loaded file is a custom saved format
        function isTrue = isFromSavedFile(app)
            if app.UITable.Data{1,1} == "Comments" && app.UITable.Data{1,2} == "Window" && app.UITable.Data{1,3} == "Smoothing"
                isTrue = true;
            else
                isTrue = false;
            end
        end
    
    	%Update the settings (window, smoothing, etc) if the read data is coming from a saved file
        function updateSettings(app)
            if isFromSavedFile(app) == true
                app.Comments.Value = app.UITable.Data{2,1};         %Zet de eerder opgeslagen commentaar al in de textbox
                app.WindowDropDown.Value = app.UITable.Data{2,2};   %Zet de selector van de window op de eerder ingestelde waarde
                if app.UITable.Data{2,3} == "-1"                    %If smoothing was set to 'off'
                    app.Switch.Value = app.Switch.Items{1};         %Turn the switch to false (1st element)
                    app.smoothOn = false;                           %Smoothing is off
                    
                    app.WindowSizeSlider.Value = 1;                 %Set the slider to 1    
                    app.WindowSizeSlider.Enable = 'off';            %Disable the slider
                else                                                %If smoothing was set to 'on'
                    app.Switch.Value = app.Switch.Items{2};         %Set the switch to 'on' (2nd element)
                    app.smoothOn = true;                            %Smoothing is on
                    
                    app.WindowSizeSlider.Value = str2double(app.UITable.Data{2,3});   %Set the slider to its previous value
                    app.WindowSizeSlider.Enable = 'on';                   %Enable the slider
                end
            end
        end
        
        %Fourier
        %X: healthy and Y: Cerebral Palsy
        function calculate(app)
            if app.smoothOn == true
                inputX = app.healthyDataSmoothed;
                inputY = app.cerebralPalsyDataSmoothed;
            else
                inputX = app.healthyData;
                inputY = app.cerebralPalsyData;
            end


            Fs = 200;                   %Sample frequency
            L1 = length(inputX);        %Data of the healthy subject
            L2 = length(inputY);        %Data of the subject with cerebral palsy, has to be equal to L1
            n = 2^nextpow2(L1);         %Calculate next power of 2 after the length of the input used for fft.
            
             w = 1;                     %Default = no windowing
             window = app.WindowDropDown.Value
             window = lower(window);    %Choose the appropriate windowing function.
                if strcmp(window,'rectangular window')
                    w=rectwin(L1);
                elseif strcmp(window,'bartlett window')
                    w=bartlett(L1);
                elseif strcmp(window,'hann window')
                    w=hann(L1);
                elseif strcmp(window,'hamming window')
                    w=hamming(L1);
                elseif strcmp(window,'blackman window')
                    w=blackman(L1);
                else
                    w=1;                %No windowing
                end
             app.WindowDropDown.Value

            Y1 = fft(w.*inputX,n);      %Calculate the fft of the input data multiplied 
            Y2 = fft(w.*inputY,n);      % by the window. Zeropadded to a length of n
            
            Y1 = abs(Y1/L1);            %Take the absolute value of the fft-data devided by the length
            Y2 = abs(Y2/L2);            %Take the amplitude(magnitude) of the fft. 
                                        %Normalizing the values by dividing by the
                                        %sample points(length)
            
            Y1 = Y1(1:L1/2+1);          %Compute single-sided spectrum base on the 
            Y2 = Y2(1:L2/2+1);          %Double sided spectrum and the even-valued 
                                        %signal length L1 or L2
            
            Y1(2:end-1) = 2*Y1(2:end-1); %Due to the power being divided between the 
            Y2(2:end-1) = 2*Y2(2:end-1); %positive and negative side of the spectrum
                                         %The negative part is lost by calculating the 
                                         %onesided spectrum. This can be counteracted
                                         %by multiplying by 2
            
            val = Fs*(0:(L1/2))/L1;      %Calculate the amount of frequency bins for 
                                         %plotting the graphs
                                         
            t1 = interp1([min(val), max(val)],[min(inputX), max(inputX)], val);
            t2 = interp1([min(val), max(val)],[min(inputY), max(inputY)], val);
            %The fft returns frequency bins but the data is in degrees. Therefore we
            %change these values by interpolating them between the maximum and minimum
            %amount of degrees. And we use these values for plotting.
            
            
            showOutputData(app, t1, Y1 ,t2 ,Y2);
        end
        
        function showOutputData(app, T1, Y1, T2, Y2)
            cla(app.outputAxes); %clear graph
            
            if app.HealthyCheckBox.Value == 1 %If healthy is on: draw healthy data
                plot(app.outputAxes, T1, Y1)
                if app.CerebralPalsyCheckBox.Value == 1 %If other data needs to be plotted, hold on
                    hold(app.outputAxes, 'on');
                end
                legend(app.outputAxes,'Healthy')
            end
            
            if app.CerebralPalsyCheckBox.Value == 1
                plot(app.outputAxes, T2,Y2);
                if app.HealthyCheckBox.Value == 1 %Hold off if hold on was used before
                    %hold(app.inputAxes, 'off');
                end
                legend(app.outputAxes,'CerebralPalsy')
            end
            
            if app.CerebralPalsyCheckBox.Value == 1 && app.HealthyCheckBox.Value == 1 
                legend(app.outputAxes,'Healthy','CerebralPalsy')
            end
    
        end
    end 


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.WindowSizeSlider.Enable = 'off';
        end

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            warning('OFF', 'MATLAB:table:ModifiedAndSavedVarnames');   %Disable a warning which, otherwise, is thrown every time
            [file, path] = uigetfile('*.xlsx');                        %Open a file explorer which can only load an Excel-file and load the selected file
            if isequal(file,0)                                         %This is true if the action was canceled for any reason 
                uialert(app.UIFigure, "Please input an Excel file.","Notification");    %Notify the user with a pop-up
            else
                [A,B] = xlsfinfo(fullfile(path, file));     %Get (xls)info of the Excel-file so that existance of sheets can be checked
                if any(strcmp(B, 'Sheet1'))                 %If there is a sheet named Sheet1: load this one, otherwise the first sheet is loaded
                    t = readtable(fullfile(path, file), 'Sheet','Sheet1', "ReadVariableNames",false);   %ReadVariableNames false to also read the first line of the file (otherwise they're stored as headers of the table)
                else
                    t = readtable(fullfile(path, file), "ReadVariableNames",false);
                end
                app.UITable.Data = t;   %Save the loaded Excel data in the UITable
            end
            fullpathname = strcat(path, file);              %Get the full pathname and place it in the textfield next to 'open file'
            app.CurrentfileEditField.Value = fullpathname;
            
            %Update the data settings if data is being read from a custom file format
            updateSettings(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            selectedCells = event.Indices;      %Saves indices of selected cells
            [m,n] = size( selectedCells )       %Get the height and width of table
            firstRow    = selectedCells(1,1);   lastRow     = selectedCells(m,1);   %get the first and last row number from the indices table
            firstColumn = selectedCells(1,n);   lastColumn  = selectedCells(m,n);   %get the first and last column number from the  indices tabel
            
            if firstColumn == lastColumn && firstRow == lastRow                 %check if only one cell is selected
                numRows = height(app.UITable.Data(:,firstColumn));              %get the number of total data rows
                selectedData = app.UITable.Data(firstRow:numRows,firstColumn);  %select the data from the selected cell, to the last row
                selectedData = table2array( selectedData );                     %transform the table to an array
                doubleData = str2double ( selectedData );                       %convert to double

                if app.HealthyButton.Value == 1     %check what kind of data was selected (Healthy or Cerebral Palsy)
                    app.healthyData(:,:) = [];                                                      %empty the data
                    app.healthyData(1:numRows-firstRow,1) = doubleData(1:numRows-firstRow,1);       %store the selected data
                    showInputData(app, app.healthyData, app.cerebralPalsyData);
                else 
                    app.cerebralPalsyData(:,:) = [];                                                %empty the data
                    app.cerebralPalsyData(1:numRows-firstRow,1) = doubleData(1:numRows-firstRow,1); %store the selected data
                    showInputData(app, app.healthyData, app.cerebralPalsyData);
                end
            else
                uialert(app.UIFigure, "Please only select one cell! Changes have not been saved.","Notification");  %notify the user if the data selection was canceled
            end
        end

        % Value changed function: HealthyCheckBox
        function HealthyCheckBoxValueChanged(app, event)
            showInputData(app, app.healthyData, app.cerebralPalsyData);
            calculate(app);
        end

        % Value changed function: CerebralPalsyCheckBox
        function CerebralPalsyCheckBoxValueChanged(app, event)
            showInputData(app, app.healthyData, app.cerebralPalsyData);
            calculate(app);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Disable HELP button while dialog is open
            app.HelpButton.Enable = 'off';           
            % Open the HELP dialog and pass inputs
            app.HelpPopup = Help(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.smoothOn == false 
                 app.smoothOn = true;
                 app.WindowSizeSlider.Enable = 'on';
                 window = app.WindowSizeSlider.Value;
                 %smooth the data
                 app.healthyDataSmoothed = smoothdata(app.healthyData,'gaussian',window);
                 app.cerebralPalsyDataSmoothed = smoothdata(app.cerebralPalsyData ,'gaussian',window);
                 showInputData(app,app.healthyDataSmoothed, app.cerebralPalsyDataSmoothed);
                 calculate(app)
            else 
                 app.smoothOn = false;
                 app.WindowSizeSlider.Enable = 'off';
                 %if smooth isn't active, show non smoothed data
                 showInputData(app,app.healthyData, app.cerebralPalsyData);
                 
            end 
        end

        % Value changing function: WindowSizeSlider
        function WindowSizeSliderValueChanging(app, event)
            window = event.Value;
            if app.smoothOn == true
               %smooth data with window indicated on slider
               app.healthyDataSmoothed = smoothdata(app.healthyData,'gaussian',window);
               app.cerebralPalsyDataSmoothed = smoothdata(app.cerebralPalsyData ,'gaussian',window);
               showInputData(app,app.healthyDataSmoothed, app.cerebralPalsyDataSmoothed); 
            end
        end

        % Value changed function: MaximaHealthyCheckBox
        function MaximaHealthyCheckBoxValueChanged(app, event)
             value = app.MaximaHealthyCheckBox.Value;
             %go to showInputData which calculates maxima and shows them
               if app.smoothOn == true
                    showInputData(app,app.healthyDataSmoothed, app.cerebralPalsyDataSmoothed);
               else 
                    showInputData(app, app.healthyData, app.cerebralPalsyData);
               end         
        end

        % Value changed function: WindowSizeSlider
        function WindowSizeSliderValueChanged(app, event)
            value = app.WindowSizeSlider.Value;
            %calculate max and minima after slider value is changed
            calculateMaxima(app);
            calculateMinima(app);
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            [file path] = uiputfile('*.xlsx');  %Open a file explorer where a save location and name can be chosen
            if isequal(file,0)                  %If the save action is aborted
                uialert(app.UIFigure, 'The data was not saved!','Notification');    %Notify the user if the data is not saved
            else
                comments = app.Comments.Value;              %Read the written comments in the save tab
                window = app.WindowDropDown.Value;          %Read the current selected value from the window selector drop-down
                if app.smoothOn == true                     %If smoothing is on: save smoothed data and read smooth value from the slider (else set it to -1)
                    smoothing = app.WindowSizeSlider.Value; %Read smoothness from slider
                    hData = app.healthyDataSmoothed;        %Save smoothed data
                    cData = app.cerebralPalsyDataSmoothed;  %Save smoothed data
                else
                    smoothing = -1;                         %Set smoothing to -1: disabled
                    hData = app.healthyData;                %Save regular data
                    cData = app.cerebralPalsyData;          %Save regular data
                end
                
                %Create a table with the settings that are currently applied to the data
                settings = [comments window smoothing];
                settings = array2table(settings, 'VariableNames', {'Comments', 'Window', 'Smoothing'});
                %Create a table for each dataset
                healthy = array2table(hData, 'VariableNames', {'HealthyData'});
                cerebralPalsy = array2table(cData, 'VariableNames', {'CerebralPalsyData'});
                
                fullPath = fullfile(path, file);                %Construct the full save path
                %Write all data to specific locations within the file
                %So that they can be found when reading a previously saved file
                writetable(settings, fullPath);
                writetable(healthy,fullPath,'Range','A3');
                writetable(cerebralPalsy,fullPath,'Range','B3');
            end
        end

        % Value changed function: MinimahealthyCheckBox
        function MinimahealthyCheckBoxValueChanged(app, event)
            value = app.MinimahealthyCheckBox.Value;
               if app.smoothOn == true
                    showInputData(app,app.healthyDataSmoothed, app.cerebralPalsyDataSmoothed);
               else 
                    showInputData(app, app.healthyData, app.cerebralPalsyData);
               end 
        end

        % Value changed function: MaximaPalsyCheckBox
        function MaximaPalsyCheckBoxValueChanged(app, event)
            value = app.MaximaPalsyCheckBox.Value;
            if app.smoothOn == true
                    showInputData(app,app.healthyDataSmoothed, app.cerebralPalsyDataSmoothed);
               else 
                    showInputData(app, app.healthyData, app.cerebralPalsyData);
               end
        end

        % Value changed function: MinimaPalsyCheckBox
        function MinimaPalsyCheckBoxValueChanged(app, event)
            value = app.MinimaPalsyCheckBox.Value;
            %go to show input data which calculates minima and shows them
            if app.smoothOn == true
                showInputData(app,app.healthyDataSmoothed, app.cerebralPalsyDataSmoothed);
           else 
                showInputData(app, app.healthyData, app.cerebralPalsyData);
           end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.calculate();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainPanel = uipanel(app.UIFigure);
            app.Tabs = uitabgroup(app.MainPanel);
            app.Data = uitab(app.Tabs);
            app.Data.Title = 'Data';
            app.UITable = uitable(app.Data);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {' '};
            app.NextdataselectionButtonGroup = uibuttongroup(app.Data);
            app.NextdataselectionButtonGroup.Title = 'Next data selection:';
            app.HealthyButton = uiradiobutton(app.NextdataselectionButtonGroup);
            app.HealthyButton.Text = 'Healthy';
            app.HealthyButton.Value = true;
            app.CerebralPalsyButton = uiradiobutton(app.NextdataselectionButtonGroup);
            app.CerebralPalsyButton.Text = 'Cerebral Palsy';
            app.Graphs = uitab(app.Tabs);
            app.Graphs.Title = 'Graphs';
            app.GraphsPanel = uipanel(app.Graphs);
            app.inputAxes = uiaxes(app.GraphsPanel);
            title(app.inputAxes, 'Input Data')
            xlabel(app.inputAxes, 'Sample number')
            ylabel(app.inputAxes, 'Angle(Deg)')
            app.outputAxes = uiaxes(app.GraphsPanel);
            title(app.outputAxes, 'FFT Data')
            xlabel(app.outputAxes, 'Angle(Deg)')
            ylabel(app.outputAxes, 'Amplitude')
            app.TabGroup = uitabgroup(app.Graphs);
            app.PlotoptionsTab = uitab(app.TabGroup);
            app.PlotoptionsTab.Title = 'Plot options';
            app.SelectdataLabel = uilabel(app.PlotoptionsTab);
            app.SelectdataLabel.Text = 'Select data';
            app.HealthyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.HealthyCheckBox.ValueChangedFcn = createCallbackFcn(app, @HealthyCheckBoxValueChanged, true);
            app.HealthyCheckBox.Text = 'Healthy';
            app.CerebralPalsyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.CerebralPalsyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CerebralPalsyCheckBoxValueChanged, true);
            app.CerebralPalsyCheckBox.Text = 'Cerebral Palsy';
            app.HealthyPeaksLabel = uilabel(app.PlotoptionsTab);
            app.HealthyPeaksLabel.Text = 'Healthy Peaks';
            app.MaximaHealthyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.MaximaHealthyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaximaHealthyCheckBoxValueChanged, true);
            app.MaximaHealthyCheckBox.Text = 'Maxima';
            app.MinimahealthyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.MinimahealthyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinimahealthyCheckBoxValueChanged, true);
            app.MinimahealthyCheckBox.Text = 'Minima';
            app.PalsyPeaksLabel = uilabel(app.PlotoptionsTab);
            app.PalsyPeaksLabel.Text = 'Palsy Peaks';
            app.MaximaPalsyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.MaximaPalsyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaximaPalsyCheckBoxValueChanged, true);
            app.MaximaPalsyCheckBox.Text = 'Maxima';
            app.MinimaPalsyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.MinimaPalsyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinimaPalsyCheckBoxValueChanged, true);
            app.MinimaPalsyCheckBox.Text = 'Minima';
            app.FourieroptionsTab = uitab(app.TabGroup);
            app.FourieroptionsTab.Title = 'Fourier options';
            app.Label = uilabel(app.FourieroptionsTab);
            app.Label.Text = '';
            app.WindowDropDownLabel = uilabel(app.FourieroptionsTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.FourieroptionsTab);
            app.WindowDropDown.Items = {'Default', 'Rectangular window', 'Bartlett window', 'Hann window', 'Hamming window', 'Blackman window'};
            app.WindowDropDown.Value = 'Default';
            app.UpdateButton = uibutton(app.FourieroptionsTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DataoptionsTab = uitab(app.TabGroup);
            app.DataoptionsTab.Title = 'Data options';
            app.SmoothingLabel = uilabel(app.DataoptionsTab);
            app.SmoothingLabel.Text = 'Smoothing';
            app.Switch = uiswitch(app.DataoptionsTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.WindowSizeSliderLabel = uilabel(app.DataoptionsTab);
            app.WindowSizeSliderLabel.Text = 'Window Size';
            app.WindowSizeSlider = uislider(app.DataoptionsTab);
            app.WindowSizeSlider.Limits = [1 10];
            app.WindowSizeSlider.MajorTicks = [1 5 10];
            app.WindowSizeSlider.MajorTickLabels = {'1', '5', '10'};
            app.WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSizeSliderValueChanged, true);
            app.WindowSizeSlider.ValueChangingFcn = createCallbackFcn(app, @WindowSizeSliderValueChanging, true);
            app.WindowSizeSlider.MinorTicks = [1 2 3 4 5 6 7 8 9];
            app.WindowSizeSlider.Value = 1;
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save data';
            app.CommentsTextAreaLabel = uilabel(app.SaveTab);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.Comments = uitextarea(app.SaveTab);
            app.Comments.Value = {'Here you can write comments that you want to save with the data.'};
            app.SaveasButton = uibutton(app.SaveTab, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as...';
            app.openFilePanel = uipanel(app.MainPanel);
            app.OpenfileButton = uibutton(app.openFilePanel, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
            app.CurrentfileEditFieldLabel = uilabel(app.openFilePanel);
            app.CurrentfileEditFieldLabel.Text = 'Current file:';
            app.CurrentfileEditField = uieditfield(app.openFilePanel, 'text');
            app.CurrentfileEditField.Editable = 'off';
            app.HelpButton = uibutton(app.openFilePanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end